classdef signalGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        txPwEditField                   matlab.ui.control.NumericEditField
        txPwEditFieldLabel              matlab.ui.control.Label
        DAQButtonGroup                  matlab.ui.container.ButtonGroup
        outputs4Button                  matlab.ui.control.RadioButton
        outputs2Button                  matlab.ui.control.RadioButton
        FilenamesPanel                  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GoButton                        matlab.ui.control.StateButton
        SelectfilepathEditField         matlab.ui.control.EditField
        SelectfilepathEditFieldLabel    matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        ProcessedfilenameEditField      matlab.ui.control.EditField
        ProcessedfilenameLabel          matlab.ui.control.Label
        RawfileprefixEditField          matlab.ui.control.EditField
        RawfileprefixEditFieldLabel     matlab.ui.control.Label
        WavesettingsPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        sinxexpoLabel                   matlab.ui.control.Label
        forsinemodLabel                 matlab.ui.control.Label
        expo4EditField                  matlab.ui.control.NumericEditField
        expo4EditFieldLabel             matlab.ui.control.Label
        expo3EditField                  matlab.ui.control.NumericEditField
        expo3EditFieldLabel             matlab.ui.control.Label
        expo2EditField                  matlab.ui.control.NumericEditField
        expo2EditFieldLabel             matlab.ui.control.Label
        expo1EditField                  matlab.ui.control.NumericEditField
        expo1EditFieldLabel             matlab.ui.control.Label
        gainCheckBox                    matlab.ui.control.CheckBox
        offsetCheckBox                  matlab.ui.control.CheckBox
        offset4EditField                matlab.ui.control.NumericEditField
        offset4EditFieldLabel           matlab.ui.control.Label
        offset3EditField                matlab.ui.control.NumericEditField
        offset3EditFieldLabel           matlab.ui.control.Label
        offset2EditField                matlab.ui.control.NumericEditField
        offset2EditFieldLabel           matlab.ui.control.Label
        offset1EditField                matlab.ui.control.NumericEditField
        offset1EditFieldLabel           matlab.ui.control.Label
        delayCheckBox                   matlab.ui.control.CheckBox
        rampspeed4EditField             matlab.ui.control.NumericEditField
        rampspeed4EditFieldLabel        matlab.ui.control.Label
        rampspeed3EditField             matlab.ui.control.NumericEditField
        rampspeed3EditFieldLabel        matlab.ui.control.Label
        rampspeed2EditField             matlab.ui.control.NumericEditField
        rampspeed2EditFieldLabel        matlab.ui.control.Label
        forrampLabel                    matlab.ui.control.Label
        forstepLabel                    matlab.ui.control.Label
        kVsLabel                        matlab.ui.control.Label
        rampspeed1EditField             matlab.ui.control.NumericEditField
        rampspeed1EditFieldLabel        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        dutyratio4EditField             matlab.ui.control.NumericEditField
        dutyratio4EditFieldLabel        matlab.ui.control.Label
        dutyratio3EditField             matlab.ui.control.NumericEditField
        dutyratio3EditFieldLabel        matlab.ui.control.Label
        dutyratio2EditField             matlab.ui.control.NumericEditField
        dutyratio2EditFieldLabel        matlab.ui.control.Label
        dutyratio1EditField             matlab.ui.control.NumericEditField
        dutyratio1EditFieldLabel        matlab.ui.control.Label
        HVamplifierLabel                matlab.ui.control.Label
        muscleLabel                     matlab.ui.control.Label
        methodLabel                     matlab.ui.control.Label
        method4DropDown                 matlab.ui.control.DropDown
        method4DropDownLabel            matlab.ui.control.Label
        method3DropDown                 matlab.ui.control.DropDown
        method3DropDownLabel            matlab.ui.control.Label
        method2DropDown                 matlab.ui.control.DropDown
        method2DropDownLabel            matlab.ui.control.Label
        method1DropDown                 matlab.ui.control.DropDown
        method1DropDownLabel            matlab.ui.control.Label
        Trek3Label_4                    matlab.ui.control.Label
        Trek4Label_4                    matlab.ui.control.Label
        PolyKLabel_2                    matlab.ui.control.Label
        Trek0Label                      matlab.ui.control.Label
        HNLabel                         matlab.ui.control.Label
        KPLabel                         matlab.ui.control.Label
        KNLabel_2                       matlab.ui.control.Label
        HPLabel_2                       matlab.ui.control.Label
        delay4EditField                 matlab.ui.control.NumericEditField
        delay4EditFieldLabel            matlab.ui.control.Label
        delay3EditField                 matlab.ui.control.NumericEditField
        delay3EditFieldLabel            matlab.ui.control.Label
        delay2EditField                 matlab.ui.control.NumericEditField
        delay2EditFieldLabel            matlab.ui.control.Label
        delay1EditField                 matlab.ui.control.NumericEditField
        delay1EditFieldLabel            matlab.ui.control.Label
        gain4EditField                  matlab.ui.control.NumericEditField
        gain4EditFieldLabel             matlab.ui.control.Label
        gain3EditField                  matlab.ui.control.NumericEditField
        gain3EditFieldLabel             matlab.ui.control.Label
        gain2EditField                  matlab.ui.control.NumericEditField
        gain2EditFieldLabel             matlab.ui.control.Label
        gain1EditField                  matlab.ui.control.NumericEditField
        gain1EditFieldLabel             matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        kVVLabel                        matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        MonitorlimittripstatusCheckBox  matlab.ui.control.CheckBox
        PressStopwhentestiscompletedtosavedataLabel  matlab.ui.control.Label
        CalibrationPanel                matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MTlengthconstantkLEditField     matlab.ui.control.NumericEditField
        MTlengthconstantkLLabel         matlab.ui.control.Label
        MTforceconstantkFEditField      matlab.ui.control.NumericEditField
        MTforceconstantkFLabel          matlab.ui.control.Label
        TREKvoltageconstantkVEditField  matlab.ui.control.NumericEditField
        TREKvoltageconstantkVLabel      matlab.ui.control.Label
        SamplerateEditField             matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel        matlab.ui.control.Label
        mmVLabel                        matlab.ui.control.Label
        NVLabel                         matlab.ui.control.Label
        VkVLabel                        matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        VoltageParametersPanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        frequencyEditField              matlab.ui.control.NumericEditField
        frequencyEditFieldLabel         matlab.ui.control.Label
        TotaltimeEditField              matlab.ui.control.NumericEditField
        TotaltimeEditFieldLabel         matlab.ui.control.Label
        MaxvoltageEditField             matlab.ui.control.NumericEditField
        MaxvoltageEditFieldLabel        matlab.ui.control.Label
        ReversepolarityCheckBox         matlab.ui.control.CheckBox
        HzLabel_5                       matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        kVLabel                         matlab.ui.control.Label
        SaverawfileCheckBox             matlab.ui.control.CheckBox
        SetupPanel_2                    matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        kneeLabel                       matlab.ui.control.Label
        encoderLabel_2                  matlab.ui.control.Label
        AI17Label                       matlab.ui.control.Label
        hipLabel                        matlab.ui.control.Label
        encoderLabel                    matlab.ui.control.Label
        AI16Label                       matlab.ui.control.Label
        currentmonitorLabel_4           matlab.ui.control.Label
        Trek4Label_3                    matlab.ui.control.Label
        AI7Label                        matlab.ui.control.Label
        voltagemonitorLabel_11          matlab.ui.control.Label
        Trek4Label_2                    matlab.ui.control.Label
        AI6Label                        matlab.ui.control.Label
        currentmonitorLabel_3           matlab.ui.control.Label
        Trek3Label_3                    matlab.ui.control.Label
        AI5Label                        matlab.ui.control.Label
        voltagemonitorLabel_10          matlab.ui.control.Label
        Trek3Label_2                    matlab.ui.control.Label
        AI4Label                        matlab.ui.control.Label
        currentmonitorLabel_2           matlab.ui.control.Label
        Trek2Label_3                    matlab.ui.control.Label
        AI3Label                        matlab.ui.control.Label
        voltagemonitorLabel_9           matlab.ui.control.Label
        Trek2Label_2                    matlab.ui.control.Label
        AI2Label                        matlab.ui.control.Label
        currentmonitorLabel             matlab.ui.control.Label
        Trek1Label_15                   matlab.ui.control.Label
        AI1Label                        matlab.ui.control.Label
        voltagemonitorLabel_8           matlab.ui.control.Label
        Trek1Label_14                   matlab.ui.control.Label
        AI0Label_8                      matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        voltageinLabel_4                matlab.ui.control.Label
        Trek4Label                      matlab.ui.control.Label
        voltageinLabel_3                matlab.ui.control.Label
        Trek3Label                      matlab.ui.control.Label
        voltageinLabel_2                matlab.ui.control.Label
        Trek2Label                      matlab.ui.control.Label
        voltageinLabel                  matlab.ui.control.Label
        Trek1Label_22                   matlab.ui.control.Label
        AO0Label_2                      matlab.ui.control.Label
        AO3Label_2                      matlab.ui.control.Label
        AO2Label_2                      matlab.ui.control.Label
        AO1Label_2                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    %% DAQ
    properties (Access = public)
        d;
        Arr;
        fullSignal;
        scanCount = 0;
        timeInit = 1;
        lastDataIndex = 0;
        kV;
        kI = 200; %todo: input from the panel
        kF;
        kL;
        type_DAQ = 2;
        rawFilename;
    end

    %% DAQ
    methods (Access = private)

        function buildSignal(app)
            timeTotal = app.TotaltimeEditField.Value;
            sampRate = app.SamplerateEditField.Value;

            % Build voltage signals
            time = (-app.timeInit: 1/sampRate: timeTotal - 1/sampRate)';


            voltageSignal_1 = createBaseSignal(app, time, app.method1DropDown.Value, app.dutyratio1EditField.Value*0.01, app.rampspeed1EditField.Value, app.offset1EditField.Value * app.offsetCheckBox.Value, app.delay1EditField.Value * app.delayCheckBox.Value, app.expo1EditField.Value);
            voltageSignal_2 = createBaseSignal(app, time, app.method2DropDown.Value, app.dutyratio2EditField.Value*0.01, app.rampspeed2EditField.Value, app.offset2EditField.Value * app.offsetCheckBox.Value, app.delay2EditField.Value * app.delayCheckBox.Value, app.expo2EditField.Value);
            voltageSignal_3 = createBaseSignal(app, time, app.method3DropDown.Value, app.dutyratio3EditField.Value*0.01, app.rampspeed3EditField.Value, app.offset3EditField.Value * app.offsetCheckBox.Value, app.delay3EditField.Value * app.delayCheckBox.Value, app.expo3EditField.Value);
            voltageSignal_4 = createBaseSignal(app, time, app.method4DropDown.Value, app.dutyratio4EditField.Value*0.01, app.rampspeed4EditField.Value, app.offset4EditField.Value * app.offsetCheckBox.Value, app.delay4EditField.Value * app.delayCheckBox.Value, app.expo4EditField.Value);

            voltageSignal_1 = voltageSignal_1/app.gain1EditField.Value;
            voltageSignal_2 = voltageSignal_2/app.gain2EditField.Value;
            voltageSignal_3 = voltageSignal_3/app.gain3EditField.Value;
            voltageSignal_4 = voltageSignal_4/app.gain4EditField.Value;

            refSignal = zeros(size(time)); % dump signal, originally used for reference signal for TF estimation

            app.fullSignal = [time, refSignal, voltageSignal_1, voltageSignal_2, voltageSignal_3, voltageSignal_4];
        end

        function voltageSignal = createBaseSignal(app, time, method, dutyRatio, rampSpd, offset, phase, expo)
            sampRate = app.SamplerateEditField.Value;
            frequency = app.frequencyEditField.Value;
            maxVoltage = app.MaxvoltageEditField.Value/app.kV;
            time_total = app.TotaltimeEditField.Value;
            time_init = app.timeInit;


            phase_delay = deg2rad(mod(phase, 360));
            time_delay = phase_delay/(2*pi*frequency);
            ind_delay = floor(time_delay*sampRate);

            signalBase = sin(2*pi*frequency*time);
            voltageSignal = zeros(size(time));


            switch method
                case 'sine'
                    voltageSignal = abs(signalBase);

                case 'sine mod'
                    voltageSignal = abs(signalBase.^expo);

                case 'triangle'
                    voltageSignal = asin(sin(2*pi*frequency*time));
                    voltageSignal = abs(voltageSignal/pi*2); % adujust for correct amplitude

                case 'sawtooth'
                    voltageSignal = 2*frequency*time - floor(2*frequency*time);

                case 'step'
                    voltageSignal = floor(dutyRatio - 2*frequency*time) - floor(-2*frequency*time);

                case 'ramped square'
                    voltageSignal = floor(dutyRatio - 2*frequency*time) - floor(-2*frequency*time); % generate a step signal

                    time_cycle = 1/frequency/2;
                    time_rmp = maxVoltage/rampSpd;
                    time_hold = 1/frequency/2*dutyRatio;

                    if time_cycle < 2*time_rmp + time_hold
                        uiwait(msgbox("'one cycle is longer than the set frequency: increase ramp speed or reduce duty ratio'", "Error", 'modal'));
                    end

                    ind_rmpTime = ceil(time_rmp*sampRate);
                    ind_holdTime = ceil(time_hold*sampRate);


                    voltageSignal = [zeros(ind_rmpTime, 1); voltageSignal(1: end - ind_rmpTime, 1)]; % shift the step signal


                    for i = 1: ceil(time_total/time_cycle)
                        ind_cycleStart = floor((time_init + (i-1)*time_cycle)*sampRate) + 1;
                        ind_holdEnd = ind_cycleStart + ind_rmpTime + ind_holdTime;
                        voltageSignal(ind_cycleStart: ind_cycleStart + ind_rmpTime) = linspace(0, 1, ind_rmpTime + 1);
                        voltageSignal(ind_holdEnd: ind_holdEnd + ind_rmpTime) = linspace(1, 0, ind_rmpTime + 1);
                    end

                    voltageSignal = voltageSignal(1:length(time));
            end

            if app.ReversepolarityCheckBox.Value
                mask = sign(signalBase); % create a mask for reverse polarity
                voltageSignal = voltageSignal.*mask; % set direction for reversed polarity
            end
            voltageSignal = [zeros(ind_delay, 1); voltageSignal(1: end - ind_delay, 1)]; % shift phase
            voltageSignal(time < time_delay) = 0;  % zero padding to avoid sudden ramp up from the phase shift

            voltageSignal = maxVoltage*voltageSignal; % set amplitude
            voltageSignal = voltageSignal + offset; % add offset

            voltageSignal(time < 0) = 0; % zero padding for initializing time

        end


        function buildPreview(app)
            maxVoltage = app.MaxvoltageEditField.Value;

            buildSignal(app);
            %             time = linspace(0, length(app.fullSignal)/sampRate, length(app.fullSignal));

            cla(app.UIAxes, "reset")
            title(app.UIAxes, 'prescribed signals')
            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.fullSignal(:, 1), app.fullSignal(:, 3)*app.kV);
            plot(app.UIAxes, app.fullSignal(:, 1), app.fullSignal(:, 4)*app.kV);
            plot(app.UIAxes, app.fullSignal(:, 1), app.fullSignal(:, 5)*app.kV);
            plot(app.UIAxes, app.fullSignal(:, 1), app.fullSignal(:, 6)*app.kV);
            % ylim(app.UIAxes, [(-1.5)*max([0, maxVoltage]), 1.5*max([0, maxVoltage])])
            ylabel(app.UIAxes, 'Voltage (kV)');
            grid(app.UIAxes, 'on')


        end

        function storeData(app, ~, ~)
            % This function is called every n = scansAvailableFcnCount data
            % points read by the DAQ

            numScansAvailable = app.d.NumScansAvailable;
            % if numScansAvailable == 0
            %     stop_measurement(app);
            %     disp('suspiciously end')
            %     goButtonSuspection(app);
            %     return;
            % end
            app.scanCount = app.scanCount + 1;


            startIndex = (app.scanCount - 1)*app.d.ScansAvailableFcnCount + 1;
            % location to put next data
            endIndex = (startIndex - 1) + numScansAvailable;
            % location of end of new data
            app.lastDataIndex = endIndex;
            % this global index tells the program where the last data
            % point is, in case of test interruption

            % Read available data from DAQ
            scanData = read(app.d, numScansAvailable, "OutputFormat", "Matrix");

            app.Arr.voltage1(startIndex: endIndex) = scanData(:, 1);
            % channel 1 is voltage input from Trek 1(in volts)

            app.Arr.current1(startIndex: endIndex) = scanData(:, 2);
            % channel 2 is current input from Trek 1 (in volts)

            app.Arr.voltage2(startIndex: endIndex) = scanData(:, 3);
            % channel 3 is voltage input from Trek 2 (in volts)

            app.Arr.current2(startIndex: endIndex) = scanData(:, 4);
            % channel 4 is current input from Trek 2 (in volts)

            app.Arr.voltage3(startIndex: endIndex) = scanData(:, 5);
            % channel 5 is voltage input from Trek 3 (in volts)

            app.Arr.current3(startIndex: endIndex) = scanData(:, 6);
            % channel 6 is current input from Trek 3 (in volts)

            app.Arr.voltage4(startIndex: endIndex) = scanData(:, 7);
            % channel 7 is voltage input from Trek 4 (in volts)

            app.Arr.current4(startIndex: endIndex) = scanData(:, 8);
            % channel 8 is current input from Trek 4 (in volts)

            if app.type_DAQ == 4

                app.Arr.angle1(startIndex: endIndex) = scanData(:, 9);
                % channel 16 is encoder angle 1 (in volts)

                app.Arr.angle2(startIndex: endIndex) = scanData(:, 10);
                % channel 17 is encoder angle 2 (in volts)

                app.Arr.fp1(startIndex: endIndex) = scanData(:, 11);
                % channel 20 is force plate X (in volts)

                app.Arr.fp2(startIndex: endIndex) = scanData(:, 12);
                % channel 21 is force plate Y (in volts)

                app.Arr.fp3(startIndex: endIndex) = scanData(:, 13);
                % channel 22 is force plate Z (in volts)

            end


            % stop the sequential plot when it is heavy to process
            %             % Plot data every
            %             app.UIAxes.Visible = 0;
            %             cla(app.UIAxes, "reset");
            %             hold(app.UIAxes, "on");
            %             title(app.UIAxes, 'sequential plot')
            %             yyaxis(app.UIAxes, 'left');
            %             plot(app.UIAxes, timeArr(startIndex: endIndex), voltageArr1(startIndex: endIndex), '-');
            %             ylabel(app.UIAxes, 'voltage')
            %
            %             yyaxis(app.UIAxes, 'right');
            %             plot(app.UIAxes, timeArr(startIndex: endIndex), currentArr1(startIndex: endIndex), '-');
            %             ylabel(app.UIAxes, 'current')
            % %             ylim(app.UIAxes(1), 'auto')
            % %             ylim(app.UIAxes(2), 'auto')
            %             grid(app.UIAxes, "on")
            %             app.UIAxes.Visible = 1;


            %             trip = scanData(end, 4);
            %             % channel 4 is limit/trip status
            %             if trip < 4 && app.MonitorlimittripstatusCheckBox.Value
            %                 app.Lamp.Color = 'red';
            %                 app.GoButton.Value = 0;
            %                 GoButtonValueChanged(app);
            %             end


            if endIndex >= length(app.Arr.time)
                stop_measurement(app);
                disp('successfully end')
                goButtonReady(app);
            end
        end

        function setArr(app)

            % Build output signal and preload
            app.Arr.time = app.fullSignal(:, 1);
            app.Arr.voltageRef1 = app.fullSignal(:, 3);
            app.Arr.voltageRef2 = app.fullSignal(:, 4);
            app.Arr.voltageRef3 = app.fullSignal(:, 5);
            app.Arr.voltageRef4 = app.fullSignal(:, 6);

            app.Arr.voltage1 = zeros(length(app.fullSignal), 1);
            app.Arr.current1 = zeros(length(app.fullSignal), 1);
            app.Arr.voltage2 = zeros(length(app.fullSignal), 1);
            app.Arr.current2 = zeros(length(app.fullSignal), 1);
            app.Arr.voltage3 = zeros(length(app.fullSignal), 1);
            app.Arr.current3 = zeros(length(app.fullSignal), 1);
            app.Arr.voltage4 = zeros(length(app.fullSignal), 1);
            app.Arr.current4 = zeros(length(app.fullSignal), 1);

            app.Arr.angle1 = zeros(length(app.fullSignal), 1);
            app.Arr.angle2 = zeros(length(app.fullSignal), 1);
            app.Arr.fp1 = zeros(length(app.fullSignal), 1);
            app.Arr.fp2 = zeros(length(app.fullSignal), 1);
            app.Arr.fp3 = zeros(length(app.fullSignal), 1);
        end

        function start_DAQ(app)

            if app.type_DAQ == 4
                preload(app.d, app.fullSignal(:, 3:6));
            else
                preload(app.d, app.fullSignal(:, 3:4));
            end

            start(app.d);


        end

        function stop_DAQ(app)
            disp('stop_DAQ: starting stop DAQ')
            if app.d.Running
                try
                    stop(app.d);
                catch
                    disp('failed to stop DAQ!!')
                end

            end

            disp('stop_DAQ: end stop DAQ')


            % Read residual data from DAQ
            if app.d.NumScansAvailable > 0
                disp('reading residual data from DAQ')
                storeData(app, app.d, 0);
            end
            disp('stop_DAQ: store data')
        end

        function plot_result(app)
            cla(app.UIAxes, "reset")
            title(app.UIAxes, 'overall plot');
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, app.Arr.time, app.Arr.voltage1);
            ylabel(app.UIAxes, 'voltage [kV]');
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, app.Arr.time, app.Arr.current1);
            ylabel(app.UIAxes, 'current [uA]');
            xlabel(app.UIAxes, 'time [s]')
            grid(app.UIAxes, "on")
        end

        function check_fname(app)

            % Check for valid filenames
            if app.ProcessedfilenameEditField == ""
                uiwait(msgbox("Empty filename", "Error", 'modal'));
                app.GoButton.Value = 0;
                buildPreview(app);
                return
            elseif app.SaverawfileCheckBox.Value && app.RawfileprefixEditField == ""
                uiwait(msgbox("Empty filename", "Error", 'modal'));
                app.GoButton.Value = 0;
                buildPreview(app);
                return
            end


            % Check for valid filenames
            if app.SaverawfileCheckBox.Value && app.RawfileprefixEditField.Value == ""
                uiwait(msgbox("Empty filename", "Error", 'modal'));
                app.GoButton.Value = 0;
                return
            elseif app.ProcessedfilenameEditField.Value == ""
                uiwait(msgbox("Empty filename", "Error", 'modal'));
                app.GoButton.Value = 0;
                return
            end
        end

        function saveDAQ(app)

            % file saves
            if app.SaverawfileCheckBox.Value
                writetable(table(...
                    app.Arr.time,...
                    app.Arr.voltageRef1*app.kV,...
                    app.Arr.voltageRef2*app.kV,...
                    app.Arr.voltageRef3*app.kV,...
                    app.Arr.voltageRef4*app.kV,...
                    app.Arr.voltage1*app.kV,...
                    app.Arr.current1*app.kI,...
                    app.Arr.voltage2*app.kV,...
                    app.Arr.current2*app.kI,...
                    app.Arr.voltage3*app.kV,...
                    app.Arr.current3*app.kI,...
                    app.Arr.voltage4*app.kV,...
                    app.Arr.current4*app.kI,...
                    app.Arr.angle1,...
                    app.Arr.angle2,...
                    app.Arr.fp1,...
                    app.Arr.fp2,...
                    app.Arr.fp3,...
                    'VariableNames', {...
                    'Time [s]',...
                    'Voltage ref 1[kV]',...
                    'Voltage ref 2[kV]',...
                    'Voltage ref 3[kV]',...
                    'Voltage ref 4[kV]',...
                    'Voltage 1 [kV]',...
                    'Current 1 [uA]',...
                    'Voltage 2 [kV]',...
                    'Current 2 [uA]',...
                    'Voltage 3 [kV]',...
                    'Current 3 [uA]',...
                    'Voltage 4 [kV]',...
                    'Current 4 [uA]',...
                    'Angle 1 [V]',...
                    'Angle 2 [V]',...
                    'force 1 [V]',...
                    'force 2 [V]',...
                    'force 3 [V]',...
                    }), [app.rawFilename, '_DAQ.dat']);
            end
            %                 processedFilename = fullfile(app.SelectfilepathEditField.Value, [app.ProcessedfilenameEditField.Value ,'_sineSweep_', textPara]);
            %                 writetable(table(processedCurve(:, 1), processedCurve(:, 2), processedCurve(:, 3), 'VariableNames',...
            %                     {'Frequency [Hz]', 'Amplitude [dB]', 'Phase [deg]'}), processedFilename);
        end

        function start_measurement(app)
            buildSignal(app);
            setArr(app);
            start_DAQ(app);
            app.scanCount = 0;
            goButtonRecording(app);
        end

        function stop_measurement(app)
            setFname(app);
            stop_DAQ(app);
            disp('stopped DAQ')
            goButtonSaving(app);
            disp('changed button')
            plot_result(app);
            disp('plotted result')
            saveDAQ(app)
            disp('saved DAQ')


            % Fush the DAQ and ensure zero voltage
            flush(app.d);
            disp('flashed DAQ')
            if app.type_DAQ == 4
                write(app.d, [0, 0, 0, 0]);
            else
                write(app.d, [0, 0]);
            end
            disp('wrote 0 to DAQ')


        end

        function goButtonReady(app)
            app.GoButton.BackgroundColor = 'green';
            app.GoButton.Text = 'Run';
            app.GoButton.Value = 0;
        end
        function goButtonSuspection(app)
            app.GoButton.BackgroundColor = 'yellow';
            app.GoButton.Text = {'incorrect', 'stop', 'data length=', num2str(app.lastDataIndex)};
            warning('check the data saved')
            dataLength = app.lastDataIndex
            app.GoButton.Value = 0;
        end
        function goButtonRecording(app)
            app.GoButton.BackgroundColor = 'red';
            app.GoButton.Text = 'Recording';
            app.GoButton.Value = 1;
        end
        function goButtonSaving(app)
            app.GoButton.BackgroundColor = 'white';
            app.GoButton.Text = 'Saving';
            app.GoButton.Value = 1;
        end

        function setup_DAQ(app)
            DQL = daqlist; % get connected device list
            DevName = DQL.DeviceID(1); % select the first one (["Dev1", "SimDev1"] or ["SimDev1"])
            % DevName = "Dev3";

            if app.DAQButtonGroup.SelectedObject.Text == "4 outputs"
                app.type_DAQ = 4;
            else
                app.type_DAQ = 2;
            end
            app.d = daq("ni");
            app.d.Rate = app.SamplerateEditField.Value;

            app.d.ScansAvailableFcn = @(src, event) storeData(app, src, event);
            % call storeData fcn when scans are available

            app.d.ScansAvailableFcnCount = 'auto';
            % d.ScansAvailableFcnCount = app.SamplerateEditField.Value*1; % every 1 second
            % by default, call storeData every cycle

            defineChannels(app, DevName);
            app.d.Channels
            flush(app.d);

        end

        function defineChannels(app, DevName)

            addoutput(app.d, DevName, "ao0", "Voltage");
            % voltage output to TREk1

            addoutput(app.d, DevName, "ao1", "Voltage");
            % voltage output to TREk2

            if app.type_DAQ == 4
                addoutput(app.d, DevName, "ao2", "Voltage");
                % voltage output to TREk3

                addoutput(app.d, DevName, "ao3", "Voltage");
                % voltage output to TREk4
            end

            addinput(app.d, DevName, "ai0", "Voltage");
            % TREK 1 voltage monitor

            addinput(app.d, DevName, "ai1", "Voltage");
            % TREK 1 current monitor

            addinput(app.d, DevName, "ai2", "Voltage");
            % TREK 2 voltage monitor

            addinput(app.d, DevName, "ai3", "Voltage");
            % TREK 2 current monitor

            addinput(app.d, DevName, "ai4", "Voltage");
            % TREK 3 voltage monitor

            addinput(app.d, DevName, "ai5", "Voltage");
            % TREK 3 current monitor

            addinput(app.d, DevName, "ai6", "Voltage");
            % TREK 4 voltage monitor

            addinput(app.d, DevName, "ai7", "Voltage");
            % TREK 4 current monitor

            if app.type_DAQ == 4
                addinput(app.d, DevName, "ai16", "Voltage");
                % hip angle

                addinput(app.d, DevName, "ai17", "Voltage");
                % knee angle

                addinput(app.d, DevName, "ai20", "Voltage");
                % force plate X

                addinput(app.d, DevName, "ai21", "Voltage");
                % force plate Y

                addinput(app.d, DevName, "ai22", "Voltage");
                % force plate Z


            end

        end
        function setFname(app)
            textPara = [...
                app.method1DropDown.Value(1), '_',...
                num2str(app.MaxvoltageEditField.Value*10,   '%02.0f'), 'kV_',...
                num2str(app.frequencyEditField.Value*10, '%03.0f'), 'Hz_',...
                num2str(app.SamplerateEditField.Value,   '%04.0f'), 'Hz_',...
                num2str(app.dutyratio2EditField.Value,   '%04.0f'), 'duty_',...
                num2str(app.expo1EditField.Value,         '%02.0f'), 'exp_',...
                num2str(app.delay1EditField.Value,       '%03.0f'), 'deg_',...
                num2str(app.delay2EditField.Value,       '%03.0f'), 'deg_',...
                num2str(app.delay3EditField.Value,       '%03.0f'), 'deg_',...
                num2str(app.delay4EditField.Value,       '%03.0f'), 'deg_',...
                ];
            app.rawFilename = fullfile(app.SelectfilepathEditField.Value,...
                [app.RawfileprefixEditField.Value, app.ProcessedfilenameEditField.Value, '_', textPara, char(datetime("now","Format", "yyyy_MM_dd_HHmm_ss"))]);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GoButton.Text = 'Wait';
            app.GoButton.BackgroundColor = 'yellow';
            %% DAQ
            setup_DAQ(app);
            app.kV = app.TREKvoltageconstantkVEditField.Value;
            app.kF = app.MTforceconstantkFEditField.Value;
            app.kL = app.MTlengthconstantkLEditField.Value;

            app.RawfileprefixEditField.Enable = 0;


            if app.MonitorlimittripstatusCheckBox.Value
                app.Lamp.Enable = 1;
                app.Lamp.Color = 'green';
            else
                app.Lamp.Enable = 0;
                app.Lamp.Color = [0.96, 0.96, 0.96];
            end

            buildPreview(app);

            goButtonReady(app);

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            filepath = uigetdir;
            try
                app.SelectfilepathEditField.Value = filepath;
            catch
                uiwait(msgbox("No filepath selected", "Warning", 'warn', 'modal'));
                app.SelectfilepathEditField.Value = "";
            end
        end

        % Value changed function: GoButton
        function GoButtonValueChanged(app, event)
            if app.GoButton.Value % start test
                start_measurement(app);
            else
                stop_measurement(app);
                goButtonSuspection(app);
            end
        end

        % Value changed function: SaverawfileCheckBox
        function SaverawfileCheckBoxValueChanged(app, event)
            if app.SaverawfileCheckBox.Value
                app.RawfileprefixEditField.Enable = 1;
            else
                app.RawfileprefixEditField.Enable = 0;
            end
        end

        % Value changed function: SamplerateEditField
        function SamplerateEditFieldValueChanged(app, event)
            app.d.Rate = app.SamplerateEditField.Value;
            % change scansAvailableFcnCount here
        end

        % Value changed function: TREKvoltageconstantkVEditField
        function TREKvoltageconstantkVEditFieldValueChanged(app, event)
            app.kV = app.TREKvoltageconstantkVEditField.Value;
        end

        % Value changed function: MTforceconstantkFEditField
        function MTforceconstantkFEditFieldValueChanged(app, event)
            app.kF = app.MTforceconstantkFEditField.Value;
        end

        % Value changed function: MTlengthconstantkLEditField
        function MTlengthconstantkLEditFieldValueChanged(app, event)
            app.kL = app.MTlengthconstantkLEditField.Value;
        end

        % Callback function
        function MinfrequencyEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: MaxvoltageEditField
        function MaxvoltageEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function NumberofvoltcyclesperstepEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: ReversepolarityCheckBox
        function ReversepolarityCheckBoxValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function LogdistributionCheckBoxValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function MaxforceEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function NumberofforcestepsEditFieldValueChanged(app, event)
            if app.NumberofforcestepsEditField.Value == 1
                app.LogdistributionCheckBox.Value = 0;
                app.LogdistributionCheckBox.Enable = 0;
            elseif ~app.LogdistributionCheckBox.Enable
                app.LogdistributionCheckBox.Enable = 1;
            end

            buildPreview(app);
        end

        % Value changed function: MonitorlimittripstatusCheckBox
        function MonitorlimittripstatusCheckBoxValueChanged(app, event)
            if app.MonitorlimittripstatusCheckBox.Value
                app.Lamp.Enable = 1;
                app.Lamp.Color = 'green';
            else
                app.Lamp.Enable = 0;
                app.Lamp.Color = [0.96, 0.96, 0.96];
            end
        end

        % Callback function
        function methodDropDownValueChanged(app, event)
            switch app.methodDropDown.Value
                case 'logarithmic'
                    app.MinfrequencyEditField.Value = max(0.01, app.MinfrequencyEditField.Value);
            end
            buildPreview(app);
        end

        % Callback function
        function MaxfrequencyEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: TotaltimeEditField
        function TotaltimeEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function ConstantforceEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: frequencyEditField
        function frequencyEditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: dutyratio1EditField
        function dutyratio1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function rampSpeedValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: rampspeed1EditField
        function rampspeed1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: gain1EditField
        function gain1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: gain2EditField
        function gain2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: gain3EditField
        function gain3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: gain4EditField
        function gain4EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: delay1EditField
        function delay1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: delay2EditField
        function delay2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: delay3EditField
        function delay3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: delay4EditField
        function delay4EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: method1DropDown
        function method1DropDownValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: method2DropDown
        function method2DropDownValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: method3DropDown
        function method3DropDownValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: method4DropDown
        function method4DropDownValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: dutyratio2EditField
        function dutyratio2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: dutyratio3EditField
        function dutyratio3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: dutyratio4EditField
        function dutyratio4EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: rampspeed2EditField
        function rampspeed2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: rampspeed3EditField
        function rampspeed3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: rampspeed4EditField
        function rampspeed4EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: delayCheckBox
        function delayCheckBoxValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function expoEditFieldValueChanging(app, event)
            buildPreview(app);
        end

        % Value changed function: expo1EditField
        function expo1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Callback function
        function CurrentSenseSwitchValueChanged(app, event)

        end

        % Value changed function: txPwEditField
        function txPwEditFieldValueChanged(app, event)
            app.CurrentSenseDongle.write(value,'uint8');
        end

        % Value changed function: offset1EditField
        function offset1EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: offset2EditField
        function offset2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: offset3EditField
        function offset3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: offset4EditField
        function offset4EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: offsetCheckBox
        function offsetCheckBoxValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: expo2EditField
        function expo2EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: expo3EditField
        function expo3EditFieldValueChanged(app, event)
            buildPreview(app);
        end

        % Value changed function: expo4EditField
        function expo4EditFieldValueChanged(app, event)
            buildPreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (kV)')
            app.SetupPanel_2 = uipanel(app.UIFigure);
            app.SetupPanel_2.Title = 'Setup';
            app.GridLayout4 = uigridlayout(app.SetupPanel_2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.AO1Label_2 = uilabel(app.GridLayout5);
            app.AO1Label_2.Text = 'AO1';
            app.AO2Label_2 = uilabel(app.GridLayout5);
            app.AO2Label_2.Text = 'AO2';
            app.AO3Label_2 = uilabel(app.GridLayout5);
            app.AO3Label_2.Text = 'AO3';
            app.AO0Label_2 = uilabel(app.GridLayout5);
            app.AO0Label_2.Text = 'AO0';
            app.Trek1Label_22 = uilabel(app.GridLayout5);
            app.Trek1Label_22.Text = 'Trek 1';
            app.voltageinLabel = uilabel(app.GridLayout5);
            app.voltageinLabel.Text = 'voltage in';
            app.Trek2Label = uilabel(app.GridLayout5);
            app.Trek2Label.Text = 'Trek 2';
            app.voltageinLabel_2 = uilabel(app.GridLayout5);
            app.voltageinLabel_2.Text = 'voltage in';
            app.Trek3Label = uilabel(app.GridLayout5);
            app.Trek3Label.Text = 'Trek 3';
            app.voltageinLabel_3 = uilabel(app.GridLayout5);
            app.voltageinLabel_3.Text = 'voltage in';
            app.Trek4Label = uilabel(app.GridLayout5);
            app.Trek4Label.Text = 'Trek 4';
            app.voltageinLabel_4 = uilabel(app.GridLayout5);
            app.voltageinLabel_4.Text = 'voltage in';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.AI0Label_8 = uilabel(app.GridLayout6);
            app.AI0Label_8.Text = 'AI0';
            app.Trek1Label_14 = uilabel(app.GridLayout6);
            app.Trek1Label_14.Text = 'Trek 1';
            app.voltagemonitorLabel_8 = uilabel(app.GridLayout6);
            app.voltagemonitorLabel_8.Text = 'voltage monitor';
            app.AI1Label = uilabel(app.GridLayout6);
            app.AI1Label.Text = 'AI1';
            app.Trek1Label_15 = uilabel(app.GridLayout6);
            app.Trek1Label_15.Text = 'Trek 1';
            app.currentmonitorLabel = uilabel(app.GridLayout6);
            app.currentmonitorLabel.Text = 'current monitor';
            app.AI2Label = uilabel(app.GridLayout6);
            app.AI2Label.Text = 'AI2';
            app.Trek2Label_2 = uilabel(app.GridLayout6);
            app.Trek2Label_2.Text = 'Trek 2';
            app.voltagemonitorLabel_9 = uilabel(app.GridLayout6);
            app.voltagemonitorLabel_9.Text = 'voltage monitor';
            app.AI3Label = uilabel(app.GridLayout6);
            app.AI3Label.Text = 'AI3';
            app.Trek2Label_3 = uilabel(app.GridLayout6);
            app.Trek2Label_3.Text = 'Trek 2';
            app.currentmonitorLabel_2 = uilabel(app.GridLayout6);
            app.currentmonitorLabel_2.Text = 'current monitor';
            app.AI4Label = uilabel(app.GridLayout6);
            app.AI4Label.Text = 'AI4';
            app.Trek3Label_2 = uilabel(app.GridLayout6);
            app.Trek3Label_2.Text = 'Trek 3';
            app.voltagemonitorLabel_10 = uilabel(app.GridLayout6);
            app.voltagemonitorLabel_10.Text = 'voltage monitor';
            app.AI5Label = uilabel(app.GridLayout6);
            app.AI5Label.Text = 'AI5';
            app.Trek3Label_3 = uilabel(app.GridLayout6);
            app.Trek3Label_3.Text = 'Trek 3';
            app.currentmonitorLabel_3 = uilabel(app.GridLayout6);
            app.currentmonitorLabel_3.Text = 'current monitor';
            app.AI6Label = uilabel(app.GridLayout6);
            app.AI6Label.Text = 'AI6';
            app.Trek4Label_2 = uilabel(app.GridLayout6);
            app.Trek4Label_2.Text = 'Trek 4';
            app.voltagemonitorLabel_11 = uilabel(app.GridLayout6);
            app.voltagemonitorLabel_11.Text = 'voltage monitor';
            app.AI7Label = uilabel(app.GridLayout6);
            app.AI7Label.Text = 'AI7';
            app.Trek4Label_3 = uilabel(app.GridLayout6);
            app.Trek4Label_3.Text = 'Trek 4';
            app.currentmonitorLabel_4 = uilabel(app.GridLayout6);
            app.currentmonitorLabel_4.Text = 'current monitor';
            app.AI16Label = uilabel(app.GridLayout6);
            app.AI16Label.Text = 'AI16';
            app.encoderLabel = uilabel(app.GridLayout6);
            app.encoderLabel.Text = 'encoder';
            app.hipLabel = uilabel(app.GridLayout6);
            app.hipLabel.Text = 'hip';
            app.AI17Label = uilabel(app.GridLayout6);
            app.AI17Label.Text = 'AI17';
            app.encoderLabel_2 = uilabel(app.GridLayout6);
            app.encoderLabel_2.Text = 'encoder';
            app.kneeLabel = uilabel(app.GridLayout6);
            app.kneeLabel.Text = 'knee';
            app.SaverawfileCheckBox = uicheckbox(app.UIFigure);
            app.SaverawfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaverawfileCheckBoxValueChanged, true);
            app.SaverawfileCheckBox.Enable = 'off';
            app.SaverawfileCheckBox.Text = 'Save raw file';
            app.SaverawfileCheckBox.Value = true;
            app.VoltageParametersPanel = uipanel(app.UIFigure);
            app.VoltageParametersPanel.Title = 'Voltage Parameters';
            app.GridLayout7 = uigridlayout(app.VoltageParametersPanel);
            app.kVLabel = uilabel(app.GridLayout7);
            app.kVLabel.Text = 'kV';
            app.sLabel = uilabel(app.GridLayout7);
            app.sLabel.Text = 's';
            app.HzLabel_5 = uilabel(app.GridLayout7);
            app.HzLabel_5.Text = 'Hz';
            app.ReversepolarityCheckBox = uicheckbox(app.GridLayout7);
            app.ReversepolarityCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReversepolarityCheckBoxValueChanged, true);
            app.ReversepolarityCheckBox.Text = 'Reverse polarity';
            app.ReversepolarityCheckBox.Value = true;
            app.MaxvoltageEditFieldLabel = uilabel(app.GridLayout7);
            app.MaxvoltageEditFieldLabel.Text = 'Max voltage';
            app.MaxvoltageEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MaxvoltageEditField.Limits = [-20 20];
            app.MaxvoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxvoltageEditFieldValueChanged, true);
            app.MaxvoltageEditField.Value = 4.9999999999;
            app.TotaltimeEditFieldLabel = uilabel(app.GridLayout7);
            app.TotaltimeEditFieldLabel.Text = 'Total time';
            app.TotaltimeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.TotaltimeEditField.Limits = [0 Inf];
            app.TotaltimeEditField.ValueChangedFcn = createCallbackFcn(app, @TotaltimeEditFieldValueChanged, true);
            app.TotaltimeEditField.Value = 4;
            app.frequencyEditFieldLabel = uilabel(app.GridLayout7);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.GridLayout7, 'numeric');
            app.frequencyEditField.Limits = [0 Inf];
            app.frequencyEditField.ValueChangedFcn = createCallbackFcn(app, @frequencyEditFieldValueChanged, true);
            app.frequencyEditField.Value = 1;
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.GridLayout2 = uigridlayout(app.CalibrationPanel);
            app.HzLabel = uilabel(app.GridLayout2);
            app.HzLabel.Text = 'Hz';
            app.VkVLabel = uilabel(app.GridLayout2);
            app.VkVLabel.Text = 'V/kV';
            app.NVLabel = uilabel(app.GridLayout2);
            app.NVLabel.Text = 'N/V';
            app.mmVLabel = uilabel(app.GridLayout2);
            app.mmVLabel.Text = 'mm/V';
            app.SamplerateEditFieldLabel = uilabel(app.GridLayout2);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.SamplerateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SamplerateEditField.Limits = [0 Inf];
            app.SamplerateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplerateEditFieldValueChanged, true);
            app.SamplerateEditField.Value = 1000;
            app.TREKvoltageconstantkVLabel = uilabel(app.GridLayout2);
            app.TREKvoltageconstantkVLabel.Enable = 'off';
            app.TREKvoltageconstantkVLabel.Text = 'TREK voltage constant (kV)';
            app.TREKvoltageconstantkVEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TREKvoltageconstantkVEditField.Limits = [0 Inf];
            app.TREKvoltageconstantkVEditField.ValueChangedFcn = createCallbackFcn(app, @TREKvoltageconstantkVEditFieldValueChanged, true);
            app.TREKvoltageconstantkVEditField.Editable = 'off';
            app.TREKvoltageconstantkVEditField.Enable = 'off';
            app.TREKvoltageconstantkVEditField.Value = 1;
            app.MTforceconstantkFLabel = uilabel(app.GridLayout2);
            app.MTforceconstantkFLabel.Enable = 'off';
            app.MTforceconstantkFLabel.Text = 'MT force constant (kF)';
            app.MTforceconstantkFEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MTforceconstantkFEditField.Limits = [0 Inf];
            app.MTforceconstantkFEditField.ValueChangedFcn = createCallbackFcn(app, @MTforceconstantkFEditFieldValueChanged, true);
            app.MTforceconstantkFEditField.Editable = 'off';
            app.MTforceconstantkFEditField.Enable = 'off';
            app.MTforceconstantkFEditField.Value = 9.96;
            app.MTlengthconstantkLLabel = uilabel(app.GridLayout2);
            app.MTlengthconstantkLLabel.Enable = 'off';
            app.MTlengthconstantkLLabel.Text = 'MT length constant (kL)';
            app.MTlengthconstantkLEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MTlengthconstantkLEditField.Limits = [0 Inf];
            app.MTlengthconstantkLEditField.ValueChangedFcn = createCallbackFcn(app, @MTlengthconstantkLEditFieldValueChanged, true);
            app.MTlengthconstantkLEditField.Editable = 'off';
            app.MTlengthconstantkLEditField.Enable = 'off';
            app.MTlengthconstantkLEditField.Value = 1.989;
            app.PressStopwhentestiscompletedtosavedataLabel = uilabel(app.UIFigure);
            app.PressStopwhentestiscompletedtosavedataLabel.Text = {'Press ''Stop'' when'; 'test is completed to'; 'save data'};
            app.MonitorlimittripstatusCheckBox = uicheckbox(app.UIFigure);
            app.MonitorlimittripstatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @MonitorlimittripstatusCheckBoxValueChanged, true);
            app.MonitorlimittripstatusCheckBox.Enable = 'off';
            app.MonitorlimittripstatusCheckBox.Text = 'Monitor limit/trip status';
            app.Lamp = uilamp(app.UIFigure);
            app.WavesettingsPanel = uipanel(app.UIFigure);
            app.WavesettingsPanel.Title = 'Wave settings';
            app.GridLayout = uigridlayout(app.WavesettingsPanel);
            app.kVVLabel = uilabel(app.GridLayout);
            app.kVVLabel.Text = 'kV / V';
            app.degLabel = uilabel(app.GridLayout);
            app.degLabel.Text = 'deg';
            app.gain1EditFieldLabel = uilabel(app.GridLayout);
            app.gain1EditFieldLabel.Text = 'gain 1';
            app.gain1EditField = uieditfield(app.GridLayout, 'numeric');
            app.gain1EditField.ValueChangedFcn = createCallbackFcn(app, @gain1EditFieldValueChanged, true);
            app.gain1EditField.Value = 1;
            app.gain2EditFieldLabel = uilabel(app.GridLayout);
            app.gain2EditFieldLabel.Text = 'gain 2';
            app.gain2EditField = uieditfield(app.GridLayout, 'numeric');
            app.gain2EditField.ValueChangedFcn = createCallbackFcn(app, @gain2EditFieldValueChanged, true);
            app.gain2EditField.Value = 1;
            app.gain3EditFieldLabel = uilabel(app.GridLayout);
            app.gain3EditFieldLabel.Text = 'gain 3';
            app.gain3EditField = uieditfield(app.GridLayout, 'numeric');
            app.gain3EditField.ValueChangedFcn = createCallbackFcn(app, @gain3EditFieldValueChanged, true);
            app.gain3EditField.Value = 1;
            app.gain4EditFieldLabel = uilabel(app.GridLayout);
            app.gain4EditFieldLabel.Text = 'gain 4';
            app.gain4EditField = uieditfield(app.GridLayout, 'numeric');
            app.gain4EditField.ValueChangedFcn = createCallbackFcn(app, @gain4EditFieldValueChanged, true);
            app.gain4EditField.Value = 1;
            app.delay1EditFieldLabel = uilabel(app.GridLayout);
            app.delay1EditFieldLabel.Text = 'delay 1';
            app.delay1EditField = uieditfield(app.GridLayout, 'numeric');
            app.delay1EditField.ValueChangedFcn = createCallbackFcn(app, @delay1EditFieldValueChanged, true);
            app.delay2EditFieldLabel = uilabel(app.GridLayout);
            app.delay2EditFieldLabel.Text = 'delay 2';
            app.delay2EditField = uieditfield(app.GridLayout, 'numeric');
            app.delay2EditField.ValueChangedFcn = createCallbackFcn(app, @delay2EditFieldValueChanged, true);
            app.delay2EditField.Value = 36;
            app.delay3EditFieldLabel = uilabel(app.GridLayout);
            app.delay3EditFieldLabel.Text = 'delay 3';
            app.delay3EditField = uieditfield(app.GridLayout, 'numeric');
            app.delay3EditField.ValueChangedFcn = createCallbackFcn(app, @delay3EditFieldValueChanged, true);
            app.delay4EditFieldLabel = uilabel(app.GridLayout);
            app.delay4EditFieldLabel.Text = 'delay 4';
            app.delay4EditField = uieditfield(app.GridLayout, 'numeric');
            app.delay4EditField.ValueChangedFcn = createCallbackFcn(app, @delay4EditFieldValueChanged, true);
            app.HPLabel_2 = uilabel(app.GridLayout);
            app.HPLabel_2.Text = 'HP';
            app.KNLabel_2 = uilabel(app.GridLayout);
            app.KNLabel_2.Text = 'KN';
            app.KPLabel = uilabel(app.GridLayout);
            app.KPLabel.Text = 'KP';
            app.HNLabel = uilabel(app.GridLayout);
            app.HNLabel.Text = 'HN';
            app.Trek0Label = uilabel(app.GridLayout);
            app.Trek0Label.Text = 'Trek 0';
            app.PolyKLabel_2 = uilabel(app.GridLayout);
            app.PolyKLabel_2.Text = 'PolyK';
            app.Trek4Label_4 = uilabel(app.GridLayout);
            app.Trek4Label_4.Text = 'Trek 4';
            app.Trek3Label_4 = uilabel(app.GridLayout);
            app.Trek3Label_4.Text = 'Trek 3';
            app.method1DropDownLabel = uilabel(app.GridLayout);
            app.method1DropDownLabel.Text = 'method 1';
            app.method1DropDown = uidropdown(app.GridLayout);
            app.method1DropDown.Items = {'sine', 'sine mod', 'step', 'ramped square', 'triangle', 'sawtooth'};
            app.method1DropDown.ValueChangedFcn = createCallbackFcn(app, @method1DropDownValueChanged, true);
            app.method1DropDown.Value = 'ramped square';
            app.method2DropDownLabel = uilabel(app.GridLayout);
            app.method2DropDownLabel.Text = 'method 2';
            app.method2DropDown = uidropdown(app.GridLayout);
            app.method2DropDown.Items = {'sine', 'sine mod', 'step', 'ramped square', 'triangle', 'sawtooth'};
            app.method2DropDown.ValueChangedFcn = createCallbackFcn(app, @method2DropDownValueChanged, true);
            app.method2DropDown.Value = 'step';
            app.method3DropDownLabel = uilabel(app.GridLayout);
            app.method3DropDownLabel.Text = 'method 3';
            app.method3DropDown = uidropdown(app.GridLayout);
            app.method3DropDown.Items = {'sine', 'sine mod', 'step', 'ramped square', 'triangle', 'sawtooth'};
            app.method3DropDown.ValueChangedFcn = createCallbackFcn(app, @method3DropDownValueChanged, true);
            app.method3DropDown.Value = 'sine';
            app.method4DropDownLabel = uilabel(app.GridLayout);
            app.method4DropDownLabel.Text = 'method 4';
            app.method4DropDown = uidropdown(app.GridLayout);
            app.method4DropDown.Items = {'sine', 'sine mod', 'step', 'ramped square', 'triangle', 'sawtooth'};
            app.method4DropDown.ValueChangedFcn = createCallbackFcn(app, @method4DropDownValueChanged, true);
            app.method4DropDown.Value = 'sine';
            app.methodLabel = uilabel(app.GridLayout);
            app.methodLabel.Text = 'method';
            app.muscleLabel = uilabel(app.GridLayout);
            app.muscleLabel.Text = 'muscle';
            app.HVamplifierLabel = uilabel(app.GridLayout);
            app.HVamplifierLabel.Text = 'HV amplifier';
            app.dutyratio1EditFieldLabel = uilabel(app.GridLayout);
            app.dutyratio1EditFieldLabel.Text = 'duty ratio 1';
            app.dutyratio1EditField = uieditfield(app.GridLayout, 'numeric');
            app.dutyratio1EditField.Limits = [0 Inf];
            app.dutyratio1EditField.ValueChangedFcn = createCallbackFcn(app, @dutyratio1EditFieldValueChanged, true);
            app.dutyratio1EditField.Value = 10;
            app.dutyratio2EditFieldLabel = uilabel(app.GridLayout);
            app.dutyratio2EditFieldLabel.Text = 'duty ratio 2';
            app.dutyratio2EditField = uieditfield(app.GridLayout, 'numeric');
            app.dutyratio2EditField.Limits = [0 Inf];
            app.dutyratio2EditField.ValueChangedFcn = createCallbackFcn(app, @dutyratio2EditFieldValueChanged, true);
            app.dutyratio2EditField.Value = 10;
            app.dutyratio3EditFieldLabel = uilabel(app.GridLayout);
            app.dutyratio3EditFieldLabel.Text = 'duty ratio 3';
            app.dutyratio3EditField = uieditfield(app.GridLayout, 'numeric');
            app.dutyratio3EditField.Limits = [0 Inf];
            app.dutyratio3EditField.ValueChangedFcn = createCallbackFcn(app, @dutyratio3EditFieldValueChanged, true);
            app.dutyratio4EditFieldLabel = uilabel(app.GridLayout);
            app.dutyratio4EditFieldLabel.Text = 'duty ratio 4';
            app.dutyratio4EditField = uieditfield(app.GridLayout, 'numeric');
            app.dutyratio4EditField.Limits = [0 Inf];
            app.dutyratio4EditField.ValueChangedFcn = createCallbackFcn(app, @dutyratio4EditFieldValueChanged, true);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '%';
            app.rampspeed1EditFieldLabel = uilabel(app.GridLayout);
            app.rampspeed1EditFieldLabel.Text = 'ramp speed 1';
            app.rampspeed1EditField = uieditfield(app.GridLayout, 'numeric');
            app.rampspeed1EditField.Limits = [0 Inf];
            app.rampspeed1EditField.ValueChangedFcn = createCallbackFcn(app, @rampspeed1EditFieldValueChanged, true);
            app.rampspeed1EditField.Value = 50;
            app.kVsLabel = uilabel(app.GridLayout);
            app.kVsLabel.Text = 'kV/s';
            app.forstepLabel = uilabel(app.GridLayout);
            app.forstepLabel.Text = 'for step';
            app.forrampLabel = uilabel(app.GridLayout);
            app.forrampLabel.Text = 'for ramp';
            app.rampspeed2EditFieldLabel = uilabel(app.GridLayout);
            app.rampspeed2EditFieldLabel.Text = 'ramp speed 2';
            app.rampspeed2EditField = uieditfield(app.GridLayout, 'numeric');
            app.rampspeed2EditField.Limits = [0 Inf];
            app.rampspeed2EditField.ValueChangedFcn = createCallbackFcn(app, @rampspeed2EditFieldValueChanged, true);
            app.rampspeed2EditField.Value = 50;
            app.rampspeed3EditFieldLabel = uilabel(app.GridLayout);
            app.rampspeed3EditFieldLabel.Text = 'ramp speed 3';
            app.rampspeed3EditField = uieditfield(app.GridLayout, 'numeric');
            app.rampspeed3EditField.Limits = [0 Inf];
            app.rampspeed3EditField.ValueChangedFcn = createCallbackFcn(app, @rampspeed3EditFieldValueChanged, true);
            app.rampspeed3EditField.Value = 50;
            app.rampspeed4EditFieldLabel = uilabel(app.GridLayout);
            app.rampspeed4EditFieldLabel.Text = 'ramp speed 4';
            app.rampspeed4EditField = uieditfield(app.GridLayout, 'numeric');
            app.rampspeed4EditField.Limits = [0 Inf];
            app.rampspeed4EditField.ValueChangedFcn = createCallbackFcn(app, @rampspeed4EditFieldValueChanged, true);
            app.rampspeed4EditField.Value = 50;
            app.delayCheckBox = uicheckbox(app.GridLayout);
            app.delayCheckBox.ValueChangedFcn = createCallbackFcn(app, @delayCheckBoxValueChanged, true);
            app.delayCheckBox.Text = 'delay';
            app.delayCheckBox.Value = true;
            app.offset1EditFieldLabel = uilabel(app.GridLayout);
            app.offset1EditFieldLabel.Text = 'offset 1';
            app.offset1EditField = uieditfield(app.GridLayout, 'numeric');
            app.offset1EditField.ValueChangedFcn = createCallbackFcn(app, @offset1EditFieldValueChanged, true);
            app.offset2EditFieldLabel = uilabel(app.GridLayout);
            app.offset2EditFieldLabel.Text = 'offset 2';
            app.offset2EditField = uieditfield(app.GridLayout, 'numeric');
            app.offset2EditField.ValueChangedFcn = createCallbackFcn(app, @offset2EditFieldValueChanged, true);
            app.offset3EditFieldLabel = uilabel(app.GridLayout);
            app.offset3EditFieldLabel.Text = 'offset 3';
            app.offset3EditField = uieditfield(app.GridLayout, 'numeric');
            app.offset3EditField.ValueChangedFcn = createCallbackFcn(app, @offset3EditFieldValueChanged, true);
            app.offset4EditFieldLabel = uilabel(app.GridLayout);
            app.offset4EditFieldLabel.Text = 'offset 4';
            app.offset4EditField = uieditfield(app.GridLayout, 'numeric');
            app.offset4EditField.ValueChangedFcn = createCallbackFcn(app, @offset4EditFieldValueChanged, true);
            app.offsetCheckBox = uicheckbox(app.GridLayout);
            app.offsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @offsetCheckBoxValueChanged, true);
            app.offsetCheckBox.Text = 'offset';
            app.offsetCheckBox.Value = true;
            app.gainCheckBox = uicheckbox(app.GridLayout);
            app.gainCheckBox.Text = 'gain';
            app.expo1EditFieldLabel = uilabel(app.GridLayout);
            app.expo1EditFieldLabel.Text = 'expo 1';
            app.expo1EditField = uieditfield(app.GridLayout, 'numeric');
            app.expo1EditField.ValueChangedFcn = createCallbackFcn(app, @expo1EditFieldValueChanged, true);
            app.expo1EditField.Value = 2;
            app.expo2EditFieldLabel = uilabel(app.GridLayout);
            app.expo2EditFieldLabel.Text = 'expo 2';
            app.expo2EditField = uieditfield(app.GridLayout, 'numeric');
            app.expo2EditField.ValueChangedFcn = createCallbackFcn(app, @expo2EditFieldValueChanged, true);
            app.expo2EditField.Value = 2;
            app.expo3EditFieldLabel = uilabel(app.GridLayout);
            app.expo3EditFieldLabel.Text = 'expo 3';
            app.expo3EditField = uieditfield(app.GridLayout, 'numeric');
            app.expo3EditField.ValueChangedFcn = createCallbackFcn(app, @expo3EditFieldValueChanged, true);
            app.expo3EditField.Value = 2;
            app.expo4EditFieldLabel = uilabel(app.GridLayout);
            app.expo4EditFieldLabel.Text = 'expo 4';
            app.expo4EditField = uieditfield(app.GridLayout, 'numeric');
            app.expo4EditField.ValueChangedFcn = createCallbackFcn(app, @expo4EditFieldValueChanged, true);
            app.expo4EditField.Value = 2;
            app.forsinemodLabel = uilabel(app.GridLayout);
            app.forsinemodLabel.Text = 'for sine mod';
            app.sinxexpoLabel = uilabel(app.GridLayout);
            app.sinxexpoLabel.Text = 'sin(x)^expo';
            app.FilenamesPanel = uipanel(app.UIFigure);
            app.FilenamesPanel.Title = 'File names';
            app.GridLayout8 = uigridlayout(app.FilenamesPanel);
            app.RawfileprefixEditFieldLabel = uilabel(app.GridLayout8);
            app.RawfileprefixEditFieldLabel.Enable = 'off';
            app.RawfileprefixEditFieldLabel.Text = 'Raw file prefix';
            app.RawfileprefixEditField = uieditfield(app.GridLayout8, 'text');
            app.RawfileprefixEditField.Enable = 'off';
            app.RawfileprefixEditField.Value = 'raw_';
            app.ProcessedfilenameLabel = uilabel(app.GridLayout8);
            app.ProcessedfilenameLabel.Text = 'Processed filename:';
            app.ProcessedfilenameEditField = uieditfield(app.GridLayout8, 'text');
            app.ProcessedfilenameEditField.Value = 'XXX';
            app.BrowseButton = uibutton(app.GridLayout8, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectfilepathEditFieldLabel = uilabel(app.GridLayout8);
            app.SelectfilepathEditFieldLabel.Text = 'Select file path:';
            app.SelectfilepathEditField = uieditfield(app.GridLayout8, 'text');
            app.SelectfilepathEditField.Value = '\\space\fukushima\RM\prj_simpleEfficiency\experiment\20240111_appDebug';
            app.GoButton = uibutton(app.GridLayout8, 'state');
            app.GoButton.ValueChangedFcn = createCallbackFcn(app, @GoButtonValueChanged, true);
            app.GoButton.Text = 'Go';
            app.DAQButtonGroup = uibuttongroup(app.UIFigure);
            app.DAQButtonGroup.Title = 'DAQ';
            app.outputs2Button = uiradiobutton(app.DAQButtonGroup);
            app.outputs2Button.Enable = 'off';
            app.outputs2Button.Text = '2 outputs';
            app.outputs2Button.Value = true;
            app.outputs4Button = uiradiobutton(app.DAQButtonGroup);
            app.outputs4Button.Enable = 'off';
            app.outputs4Button.Text = '4 outputs';
            app.txPwEditFieldLabel = uilabel(app.UIFigure);
            app.txPwEditFieldLabel.Text = 'txPw';
            app.txPwEditField = uieditfield(app.UIFigure, 'numeric');
            app.txPwEditField.ValueChangedFcn = createCallbackFcn(app, @txPwEditFieldValueChanged, true);
            app.txPwEditField.Value = 80;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end